classdef interfaz1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EcualizadorUIFigure     matlab.ui.Figure
        Bajos1130HzSliderLabel  matlab.ui.control.Label
        Bajos1130HzSlider       matlab.ui.control.Slider
        Bajos2240HzSliderLabel  matlab.ui.control.Label
        Bajos2240HzSlider       matlab.ui.control.Slider
        Bajos3500HzSliderLabel  matlab.ui.control.Label
        Bajos3500HzSlider       matlab.ui.control.Slider
        Medio12kHzSliderLabel   matlab.ui.control.Label
        Medio12kHzSlider        matlab.ui.control.Slider
        Altos124kHzSliderLabel  matlab.ui.control.Label
        Altos124kHzSlider       matlab.ui.control.Slider
        Altos27kHzSliderLabel   matlab.ui.control.Label
        Altos27kHzSlider        matlab.ui.control.Slider
        Altos310kHzSliderLabel  matlab.ui.control.Label
        Altos310kHzSlider       matlab.ui.control.Slider
        BienvenidoLabel         matlab.ui.control.Label
        AleatorioButton         matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: AleatorioButton
        function AleatorioButtonPushed(app, event)
            vals = rand(1,7)*2;
            app.Bajos1130HzSlider.Value = vals(1);
            Bajos1130HzSliderValueChanged(app,event);
            app.Bajos2240HzSlider.Value = vals(2);
            Bajos2240HzSliderValueChanged(app,event);
            app.Bajos3500HzSlider.Value = vals(3);
            Bajos3500HzSliderValueChanged(app,event);
            app.Medio12kHzSlider.Value = vals(4);
            Medio12kHzSliderValueChanged(app,event);
            app.Altos124kHzSlider.Value = vals(5);
            Altos124kHzSliderValueChanged(app,event);
            app.Altos27kHzSlider.Value = vals(6);
            Altos27kHzSliderValueChanged(app,event);
            app.Altos310kHzSlider.Value = vals(7);
            Altos310kHzSliderValueChanged(app,event);
        end

        % Value changed function: Altos124kHzSlider
        function Altos124kHzSliderValueChanged(app, event)
            value = app.Altos124kHzSlider.Value;
            ecualizador
            set_param('ecualizador/Gain4','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Altos27kHzSlider
        function Altos27kHzSliderValueChanged(app, event)
            value = app.Altos27kHzSlider.Value;
            ecualizador
            set_param('ecualizador/Gain5','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Altos310kHzSlider
        function Altos310kHzSliderValueChanged(app, event)
            value = app.Altos310kHzSlider.Value;
            ecualizador
            set_param('ecualizador/Gain6','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Bajos1130HzSlider
        function Bajos1130HzSliderValueChanged(app, event)
            value = app.Bajos1130HzSlider.Value;
            ecualizador
            set_param('ecualizador/Gain','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Bajos2240HzSlider
        function Bajos2240HzSliderValueChanged(app, event)
            value = app.Bajos2240HzSlider.Value;
            ecualizador
            set_param('ecualizador/Gain1','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Bajos3500HzSlider
        function Bajos3500HzSliderValueChanged(app, event)
            value = app.Bajos3500HzSlider.Value;
            ecualizador
            set_param('ecualizador/Gain2','Gain',sprintf('%.6f',value))
        end

        % Value changed function: Medio12kHzSlider
        function Medio12kHzSliderValueChanged(app, event)
            value = app.Medio12kHzSlider.Value;
            ecualizador
            set_param('ecualizador/Gain3','Gain',sprintf('%.6f',value))
        end

        % Callback function
        function Slider7ValueChanging(app, event)
            value = event.Value;
            ecualizador
            set_param('ecualizador/Gain6','Gain',sprintf('%.6f',value))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EcualizadorUIFigure = uifigure;
            app.EcualizadorUIFigure.Name = 'Ecualizador';
            app.Bajos1130HzSliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Bajos1130HzSliderLabel.Text = 'Bajos 1 (130 Hz)';
            app.Bajos1130HzSlider = uislider(app.EcualizadorUIFigure);
            app.Bajos1130HzSlider.Limits = [0 2];
            app.Bajos1130HzSlider.MajorTicks = [];
            app.Bajos1130HzSlider.MajorTickLabels = {};
            app.Bajos1130HzSlider.ValueChangedFcn = createCallbackFcn(app, @Bajos1130HzSliderValueChanged, true);
            app.Bajos1130HzSlider.MinorTicks = [];
            app.Bajos1130HzSlider.Value = 1;
            app.Bajos2240HzSliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Bajos2240HzSliderLabel.Text = 'Bajos 2 (240 Hz)';
            app.Bajos2240HzSlider = uislider(app.EcualizadorUIFigure);
            app.Bajos2240HzSlider.Limits = [0 2];
            app.Bajos2240HzSlider.MajorTicks = [];
            app.Bajos2240HzSlider.MajorTickLabels = {};
            app.Bajos2240HzSlider.ValueChangedFcn = createCallbackFcn(app, @Bajos2240HzSliderValueChanged, true);
            app.Bajos2240HzSlider.MinorTicks = [];
            app.Bajos2240HzSlider.Value = 1;
            app.Bajos3500HzSliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Bajos3500HzSliderLabel.Text = 'Bajos 3 (500 Hz)';
            app.Bajos3500HzSlider = uislider(app.EcualizadorUIFigure);
            app.Bajos3500HzSlider.Limits = [0 2];
            app.Bajos3500HzSlider.MajorTicks = [];
            app.Bajos3500HzSlider.MajorTickLabels = {};
            app.Bajos3500HzSlider.ValueChangedFcn = createCallbackFcn(app, @Bajos3500HzSliderValueChanged, true);
            app.Bajos3500HzSlider.MinorTicks = [];
            app.Bajos3500HzSlider.Value = 1;
            app.Medio12kHzSliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Medio12kHzSliderLabel.Text = 'Medio (1.2 kHz)';
            app.Medio12kHzSlider = uislider(app.EcualizadorUIFigure);
            app.Medio12kHzSlider.Limits = [0 2];
            app.Medio12kHzSlider.MajorTicks = [];
            app.Medio12kHzSlider.MajorTickLabels = {};
            app.Medio12kHzSlider.ValueChangedFcn = createCallbackFcn(app, @Medio12kHzSliderValueChanged, true);
            app.Medio12kHzSlider.MinorTicks = [];
            app.Medio12kHzSlider.Value = 1;
            app.Altos124kHzSliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Altos124kHzSliderLabel.Text = 'Altos 1 (2.4 kHz)';
            app.Altos124kHzSlider = uislider(app.EcualizadorUIFigure);
            app.Altos124kHzSlider.Limits = [0 2];
            app.Altos124kHzSlider.MajorTicks = [];
            app.Altos124kHzSlider.MajorTickLabels = {};
            app.Altos124kHzSlider.ValueChangedFcn = createCallbackFcn(app, @Altos124kHzSliderValueChanged, true);
            app.Altos124kHzSlider.MinorTicks = [];
            app.Altos124kHzSlider.Value = 1;
            app.Altos27kHzSliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Altos27kHzSliderLabel.Text = 'Altos 2 (7 kHz)';
            app.Altos27kHzSlider = uislider(app.EcualizadorUIFigure);
            app.Altos27kHzSlider.Limits = [0 2];
            app.Altos27kHzSlider.MajorTicks = [];
            app.Altos27kHzSlider.MajorTickLabels = {};
            app.Altos27kHzSlider.ValueChangedFcn = createCallbackFcn(app, @Altos27kHzSliderValueChanged, true);
            app.Altos27kHzSlider.MinorTicks = [];
            app.Altos27kHzSlider.Value = 1;
            app.Altos310kHzSliderLabel = uilabel(app.EcualizadorUIFigure);
            app.Altos310kHzSliderLabel.Text = 'Altos 3 (10 kHz)';
            app.Altos310kHzSlider = uislider(app.EcualizadorUIFigure);
            app.Altos310kHzSlider.Limits = [0 2];
            app.Altos310kHzSlider.MajorTicks = [];
            app.Altos310kHzSlider.MajorTickLabels = {};
            app.Altos310kHzSlider.ValueChangedFcn = createCallbackFcn(app, @Altos310kHzSliderValueChanged, true);
            app.Altos310kHzSlider.MinorTicks = [];
            app.Altos310kHzSlider.Value = 1;
            app.BienvenidoLabel = uilabel(app.EcualizadorUIFigure);
            app.BienvenidoLabel.Text = '¡Bienvenido!';
            app.AleatorioButton = uibutton(app.EcualizadorUIFigure, 'push');
            app.AleatorioButton.ButtonPushedFcn = createCallbackFcn(app, @AleatorioButtonPushed, true);
            app.AleatorioButton.Text = 'Aleatorio';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interfaz1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EcualizadorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EcualizadorUIFigure)
        end
    end
end